classdef hough_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SelectanimagehereLabel  matlab.ui.control.Label
        BrowseButton            matlab.ui.control.Button
        UIAxes_4                matlab.ui.control.UIAxes
        UIAxes_3                matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)
                    % Button pushed function: Button
%             [file,path] = uigetfile('*.jpg')  %open a mat file
       
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
                global I;
                [filename, pathname] = uigetfile('*.*', 'Pick an Image');
                filename=strcat(pathname,filename);
                I=imread(filename);
                imshow(I,'Parent',app.UIAxes);
                %%
                I = rgb2gray(I);
%                 rotI = imrotate(I,33,'crop');
                BW = edge(I,'canny');
                imshow(BW,'Parent',app.UIAxes_2);
                %%
                [H,T,R] = hough(BW);
%                 f = figure('visible','off');
                imshow(H,[],'XData',T,'YData',R,...
                            'InitialMagnification','fit','Parent',app.UIAxes_3);
                xlabel(app.UIAxes_3,'\theta')
                ylabel(app.UIAxes_3,'\rho')
                axis(app.UIAxes_3, 'normal')
                hold(app.UIAxes_3,"on")
%
                P  = houghpeaks(H,5,'threshold',ceil(0.3*max(H(:))));
                x = T(P(:,2)); y = R(P(:,1));
                plot(x,y,'s','color','red','Parent',app.UIAxes_3);
                %%
                %%
                lines = houghlines(BW,T,R,P,'FillGap',5,'MinLength',7);
                imshow(I,'Parent',app.UIAxes_4) 
                hold(app.UIAxes_4, 'on')
                max_len = 0;
                for k = 1:length(lines)
                   xy = [lines(k).point1; lines(k).point2];
                   plot(xy(:,1),xy(:,2),'LineWidth',2,'Color','green', 'Parent',app.UIAxes_4);
                
                   % Plot beginnings and ends of lines
                   plot(xy(1,1),xy(1,2),'x','LineWidth',2,'Color','yellow','Parent',app.UIAxes_4);
                   plot(xy(2,1),xy(2,2),'x','LineWidth',2,'Color','red','Parent',app.UIAxes_4);
                
                   % Determine the endpoints of the longest line segment
                   len = norm(lines(k).point1 - lines(k).point2);
                   if ( len > max_len)
                      max_len = len;
%                       xy_long = xy;
                   end
                end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'The Original')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'The Edge Image')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '\rho-\theta Space')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Lines Detected')
            zlabel(app.UIAxes_4, 'Z')
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SelectanimagehereLabel = uilabel(app.UIFigure);
            app.SelectanimagehereLabel.Text = 'Select an image here';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hough_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end